classdef hstapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GeometryLabel                   matlab.ui.control.Label
        FlowConditionsLabel             matlab.ui.control.Label
        BaseLengthmEditFieldLabel       matlab.ui.control.Label
        BaseLengthmEditField            matlab.ui.control.NumericEditField
        BasethicknessmEditFieldLabel    matlab.ui.control.Label
        BasethicknessmEditField         matlab.ui.control.NumericEditField
        FinLengthmEditFieldLabel        matlab.ui.control.Label
        FinLengthmEditField             matlab.ui.control.NumericEditField
        AirFlowRateLPMEditFieldLabel    matlab.ui.control.Label
        AirFlowRateLPMEditField         matlab.ui.control.NumericEditField
        InletTemperatureKEditFieldLabel  matlab.ui.control.Label
        InletTemperatureKEditField      matlab.ui.control.NumericEditField
        BaseTemperatureKEditFieldLabel  matlab.ui.control.Label
        BaseTemperatureKEditField       matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        FinDiametermEditFieldLabel      matlab.ui.control.Label
        FinDiametermEditField           matlab.ui.control.EditField
        TransversenumberoffinsEditFieldLabel  matlab.ui.control.Label
        TransversenumberoffinsEditField  matlab.ui.control.EditField
        LongitudinalnumberoffinsEditFieldLabel  matlab.ui.control.Label
        LongitudinalnumberoffinsEditField  matlab.ui.control.EditField
        HeatLossWEditField_2Label       matlab.ui.control.Label
        HeatLossWEditField_2            matlab.ui.control.EditField
        PressureDropPaEditField_2Label  matlab.ui.control.Label
        PressureDropPaEditField_2       matlab.ui.control.EditField
        GeometryLabel_2                 matlab.ui.control.Label
        PerformanceParametersLabel      matlab.ui.control.Label
        WeightgEditFieldLabel           matlab.ui.control.Label
        WeightgEditField                matlab.ui.control.EditField
        HeatSinkDesignerLabel           matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
AFR=app.AirFlowRateLPMEditField.Value;
L=app.FinLengthmEditField.Value;
Tbase=app.BaseTemperatureKEditField.Value;
Ti=app.InletTemperatureKEditField.Value;
basethk=app.BasethicknessmEditField.Value;
len=app.BaseLengthmEditField.Value;
%--------------------
rho=1.186;
mass_flow_rate=AFR*rho/60000;
i=1;
for D=0.003:0.001:0.007
for Nt=3:10
%     D=0.003;
%     Nt=3;
Nl=Nt*2;
Sl=len/(2*Nt);
St=2*Sl;
Ts=Tbase;

Sd=sqrt(Sl^2+(0.5*St)^2);
% Properties at 25 C

vis=0.00001552;
k=0.02625;
cp=1005;
Pr=0.71;

flow_area=len*L;
V=mass_flow_rate/(rho*flow_area);

if Sd<(St+D)/2
    Vmax=St*V/(2*(Sd-D));
else
    Vmax=St*V/(St-D);
end

Re=Vmax*D/vis;

if Re>10 && Re<100
    Nu=0.8*Re^0.4*Pr^0.36;
else if Re>100 && Re<1000
Nu=0.683*Re^0.466*Pr^0.333;
    else if Re>1000 && Re<200000 && St/Sl<2 
            Nu=0.35*(St/Sl)^0.2*Re^0.6*Pr^0.36;
            else if Re>1000 && Re<200000 && St/Sl==2 
            Nu=0.4*Re^0.6*Pr^0.36;
                else if Re>1000 && Re<200000 && St/Sl>2
                Nu=0.4*Re^0.6*Pr^0.36;
                else 
                Nu=0.022*Re^0.84*Pr^0.36;
                end
        end
    end
end
end
switch Nl
    case 1
        cf=0.7;
    case 2 
        cf=0.8;
    case 3 
        cf=0.86;
    case 4
        cf=0.9;
    case 5 
        cf=0.92;
    case 6 
        cf=0.935;
    case 7
        cf=0.95;
    case 8 
        cf=0.9566;
    case 9 
        cf=0.9633;
    case 10
        cf=0.97;
    case 11
        cf=0.98;
    case 12
        cf=0.98;
    case 13
        cf=0.98;
    case 14
        cf=0.99;
    case 15
        cf=0.99;
    case 16
        cf=0.99;
    otherwise 
        cf=1;
end

h=cf*Nu*k/D;
To=Ts-(Ts-Ti)*exp(-(3.1416*D*Nl*Nt*h)/(rho*V*Nt*St*cp));

Tlm=((Ts-Ti)-(Ts-To))/log((Ts-Ti)/(Ts-To));

q=Nl*Nt*h*pi()*D*Tlm*L;

fd=((St-D)/D)^1.08;
f=(0.25+(0.118/fd))*Re^-0.16;

mu_b=18.84e-6;
mu_w=21.74e-6;
vr=(mu_w/mu_b)^0.14;
dp=2*f*Nt*(Vmax*rho)^2*vr/rho;

weight=((len*len*basethk)+(Nt*Nl)*pi()*0.25*D^2*L)*2700;

% X=['For Diameter ',num2str(D*1000),' mm and Nt ',num2str(Nt),' heat dissipated is ',num2str(q),' W, pressure drop is ',num2str(dp),' Pa and weight is ',num2str(weight*1000),' g.'];
% disp(X);

merit=q*0.5+(10-dp)*2+(200-weight)*0.5;

    Dm(i)=D;
    Ntm(i)=Nt;
    Nlm(i)=Nl;
    hm(i)=h;
    qm(i)=round(q,0);
    dpm(i)=round(dp,1);
    weightm(i)=round(weight*1000,0);
    meritm(i)=merit;
    
i=i+1;
end
end

mm=max(meritm);
mmp=find(meritm==mm,1);

app.FinDiametermEditField.Value=num2str(Dm(mmp));
app.TransversenumberoffinsEditField.Value=num2str(Ntm(mmp));
app.LongitudinalnumberoffinsEditField.Value=num2str(Nlm(mmp));
app.WeightgEditField.Value=num2str(weightm(mmp));
app.PressureDropPaEditField_2.Value=num2str(dpm(mmp));
app.HeatLossWEditField_2.Value=num2str(qm(mmp));


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.GeometryLabel = uilabel(app.UIFigure);
            app.GeometryLabel.Text = 'Geometry';
            app.FlowConditionsLabel = uilabel(app.UIFigure);
            app.FlowConditionsLabel.Text = 'Flow Conditions';
            app.BaseLengthmEditFieldLabel = uilabel(app.UIFigure);
            app.BaseLengthmEditFieldLabel.Text = 'Base Length (m)';
            app.BaseLengthmEditField = uieditfield(app.UIFigure, 'numeric');
            app.BasethicknessmEditFieldLabel = uilabel(app.UIFigure);
            app.BasethicknessmEditFieldLabel.Text = 'Base thickness (m)';
            app.BasethicknessmEditField = uieditfield(app.UIFigure, 'numeric');
            app.FinLengthmEditFieldLabel = uilabel(app.UIFigure);
            app.FinLengthmEditFieldLabel.Text = 'Fin Length (m)';
            app.FinLengthmEditField = uieditfield(app.UIFigure, 'numeric');
            app.AirFlowRateLPMEditFieldLabel = uilabel(app.UIFigure);
            app.AirFlowRateLPMEditFieldLabel.Text = 'Air Flow Rate (LPM)';
            app.AirFlowRateLPMEditField = uieditfield(app.UIFigure, 'numeric');
            app.InletTemperatureKEditFieldLabel = uilabel(app.UIFigure);
            app.InletTemperatureKEditFieldLabel.Text = 'Inlet Temperature (K)';
            app.InletTemperatureKEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaseTemperatureKEditFieldLabel = uilabel(app.UIFigure);
            app.BaseTemperatureKEditFieldLabel.Text = 'Base Temperature (K)';
            app.BaseTemperatureKEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate!';
            app.FinDiametermEditFieldLabel = uilabel(app.UIFigure);
            app.FinDiametermEditFieldLabel.Text = 'Fin Diameter (m)';
            app.FinDiametermEditField = uieditfield(app.UIFigure, 'text');
            app.FinDiametermEditField.Editable = 'off';
            app.TransversenumberoffinsEditFieldLabel = uilabel(app.UIFigure);
            app.TransversenumberoffinsEditFieldLabel.Text = 'Transverse number of fins';
            app.TransversenumberoffinsEditField = uieditfield(app.UIFigure, 'text');
            app.TransversenumberoffinsEditField.Editable = 'off';
            app.LongitudinalnumberoffinsEditFieldLabel = uilabel(app.UIFigure);
            app.LongitudinalnumberoffinsEditFieldLabel.Text = 'Longitudinal number of fins';
            app.LongitudinalnumberoffinsEditField = uieditfield(app.UIFigure, 'text');
            app.LongitudinalnumberoffinsEditField.Editable = 'off';
            app.HeatLossWEditField_2Label = uilabel(app.UIFigure);
            app.HeatLossWEditField_2Label.Text = 'Heat Loss (W)';
            app.HeatLossWEditField_2 = uieditfield(app.UIFigure, 'text');
            app.HeatLossWEditField_2.Editable = 'off';
            app.PressureDropPaEditField_2Label = uilabel(app.UIFigure);
            app.PressureDropPaEditField_2Label.Text = 'Pressure Drop (Pa)';
            app.PressureDropPaEditField_2 = uieditfield(app.UIFigure, 'text');
            app.PressureDropPaEditField_2.Editable = 'off';
            app.GeometryLabel_2 = uilabel(app.UIFigure);
            app.GeometryLabel_2.Text = 'Geometry';
            app.PerformanceParametersLabel = uilabel(app.UIFigure);
            app.PerformanceParametersLabel.Text = 'Performance Parameters';
            app.WeightgEditFieldLabel = uilabel(app.UIFigure);
            app.WeightgEditFieldLabel.Text = 'Weight (g)';
            app.WeightgEditField = uieditfield(app.UIFigure, 'text');
            app.WeightgEditField.Editable = 'off';
            app.HeatSinkDesignerLabel = uilabel(app.UIFigure);
            app.HeatSinkDesignerLabel.Text = 'Heat Sink Designer';
        end
    end

    methods (Access = public)

        % Construct app
        function app = hstapp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end